classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        HohmannButton  matlab.ui.control.StateButton
        t0Slider       matlab.ui.control.Slider
        t0SliderLabel  matlab.ui.control.Label
        v3Slider       matlab.ui.control.Slider
        v3SliderLabel  matlab.ui.control.Label
        v2Slider       matlab.ui.control.Slider
        v2SliderLabel  matlab.ui.control.Label
        v1Slider       matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        v1SliderLabel  matlab.ui.control.Label
        ttSlider       matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mu_earth = 398600; % km^3/s^2
        Re = 6378;         % km

        % 霍曼转移计算结果存储 (用于显示)
        dv1_calc double
        dv2_calc double
        T_transfer_calc double
    end

    methods (Access = private)
        function dydt = two_body_ode(~, t, y, mu)
            % y = [x; y; z; vx; vy; vz]
            r = y(1:3);
            v = y(4:6);
            r_norm = norm(r);
            % 二体问题加速度方程
            a = -mu * r / r_norm^3;
            dydt = [v; a];
        end

        function adjustAxisLimits(app, Y1, Y2)

            % 确保 Y1 和 Y2 都是矩阵
            if size(Y2, 1) < 1
                all_points = Y1(:, 1:3);
            else
                all_points = [Y1(:, 1:3); Y2(:, 1:3)];
            end

            % 2. 找到最大的绝对坐标值 (用于确定立方体边界)
            max_val = max(abs(all_points(:)));

            % 3. 设置显示范围和留白
            min_range = 1.5; % 最小显示范围 (防止 t=0 时缩放过小)
            % 增加留白系数到 1.2 (20% 留白) 以确保全部显示
            limit = max(max_val * 1.2, min_range);

            % 4. 更新坐标轴限制，保持以原点为中心的立方体视野 (axis equal)
            app.UIAxes.XLim = [-limit, limit];
            app.UIAxes.YLim = [-limit, limit];
            app.UIAxes.ZLim = [-limit, limit];

            % 5. 设置坐标轴刻度 (Ticks)

            range_unit = 2 * limit; % 总范围

            % 目标是大约 5 个主要刻度，计算基础步长
            base_step = range_unit / 5;

            % 将 base_step 向上舍入到一个“好的”数字 (1, 2, 5, 10, 20, 50...)
            power_of_10 = 10^floor(log10(base_step));
            normalized_step = base_step / power_of_10;

            if normalized_step < 1.5
                step = power_of_10 * 1;
            elseif normalized_step < 3.5
                step = power_of_10 * 2;
            else
                step = power_of_10 * 5;
            end

            % 生成对称于零点的刻度向量
            ticks = -limit:step:limit;
            % 过滤掉超出 [-limit, limit] 的微小浮点误差值
            ticks = ticks(ticks >= -limit & ticks <= limit);

            % 强制更新刻度，解决用户提出的第一个问题
            app.UIAxes.XTick = ticks;
            app.UIAxes.YTick = ticks;
            app.UIAxes.ZTick = ticks;
        end

        function updatePlot(app)
            % 1. 获取滑块数值
            t0 = app.t0Slider.Value;      % 机动时刻
            dv = [app.v1Slider.Value; app.v2Slider.Value; app.v3Slider.Value]; % 速度增量
            tf = app.ttSlider.Value;      % 总时长
            % 2. 定义初始条件 (Initial Conditions)
            r0 = [1; 0; 0];
            v0 = [0; 1; 0]; % 假设为圆轨道
            y0 = [r0; v0];
            mu = 1; % 归一化引力常数
            % 3. 计算第一阶段：变轨前 (t = 0 -> t0)
            options = odeset('RelTol', 1e-6, 'AbsTol', 1e-6);
            [T1, Y1] = ode45(@(t,y) app.two_body_ode(t,y,mu), [0, max(0.01, t0)], y0, options);
            % 4. 施加变轨脉冲
            state_at_t0 = Y1(end, :)';
            % 在速度分量上增加 dv
            state_at_t0(4:6) = state_at_t0(4:6) + dv;
            % 5. 计算第二阶段：变轨后 (t = t0 -> tf)
            if tf > t0
                [T2, Y2] = ode45(@(t,y) app.two_body_ode(t,y,mu), [t0, tf], state_at_t0, options);
            else
                T2 = t0; Y2 = state_at_t0'; % 如果总时长小于机动时刻，不进行第二阶段
            end

            % ==================================================
            % 动态调整坐标轴范围和刻度
            app.adjustAxisLimits(Y1, Y2);
            % ==================================================

            % 6. 绘图 (Visualization)
            % 清空坐标轴
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % 绘制变轨前轨道 (对应原文紫色虚线)
            p1 = plot3(app.UIAxes, Y1(:,1), Y1(:,2), Y1(:,3), 'm--', 'LineWidth', 1.5);

            % 绘制变轨后轨道 (对应原文绿色虚线)
            if tf > t0
                p2 = plot3(app.UIAxes, Y2(:,1), Y2(:,2), Y2(:,3), 'g--', 'LineWidth', 1.5);
            else
                % 如果没有绘制第二段轨道，则将 p2 设为空，以便在 legend 中处理
                p2 = [];
            end

            % 绘制中心天体 (原点)
            p_center = plot3(app.UIAxes, 0, 0, 0, 'ko', 'MarkerFaceColor', 'b', 'MarkerSize', 10);
            % 7. 绘制箭头
            % 位置：机动发生的位置 r(t0)
            pos_t0 = Y1(end, 1:3);
            % 黄色箭头：指示机动位置 (从原点指向机动点)
            quiver3(app.UIAxes, 0, 0, 0, pos_t0(1), pos_t0(2), pos_t0(3), ...
                'Color', [1, 0.8, 0], 'LineWidth', 2, 'MaxHeadSize', 0.5, 'AutoScale', 'off');
            % 红色箭头：指示速度增量方向 (dv)
            % 起点在机动位置，方向为 dv
            if norm(dv) > 0
                quiver3(app.UIAxes, pos_t0(1), pos_t0(2), pos_t0(3), dv(1), dv(2), dv(3), ...
                    'Color', 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
            end
            % 8. **添加图例 (Legend)**
            legend_handles = [p_center, p1];
            legend_labels = {'中心天体', '变轨前轨道'};

            if ~isempty(p2)
                legend_handles = [legend_handles, p2];
                legend_labels = [legend_labels, '变轨后轨道'];
            end

            % 添加图例
            legend(app.UIAxes, legend_handles, legend_labels, 'Location', 'best');
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
        end

        function runHohmannTransfer(app)
            % 1. 定义轨道参数 (单位: km)
            h1 = 200;           % 停泊轨道高度
            h2 = 35786;         % GEO 轨道高度 (同步轨道)

            r1 = app.Re + h1;   % 停泊轨道半径
            r2 = app.Re + h2;   % 目标轨道半径

            % 2. 计算霍曼转移参数 (理论计算)
            % 2.1 速度计算
            v1_circ = sqrt(app.mu_earth / r1);        % 停泊轨道圆速度
            v2_circ = sqrt(app.mu_earth / r2);        % 目标轨道圆速度

            a_trans = (r1 + r2) / 2;                  % 转移轨道半长轴

            v1_trans = sqrt(app.mu_earth * (2/r1 - 1/a_trans)); % 转移轨道近地点速度
            v2_trans = sqrt(app.mu_earth * (2/r2 - 1/a_trans)); % 转移轨道远地点速度

            % 2.2 脉冲大小
            dv1 = v1_trans - v1_circ; % 第一次点火 (近地点加速)
            dv2 = v2_circ - v2_trans; % 第二次点火 (远地点加速)

            % 2.3 转移时间 (半个周期)
            T_trans = pi * sqrt(a_trans^3 / app.mu_earth);

            % 显示计算结果到控制台 (或你可以加 Label 显示在界面上)
            fprintf('霍曼转移计算结果:\n');
            fprintf('Delta V1 = %.4f km/s\n', dv1);
            fprintf('Delta V2 = %.4f km/s\n', dv2);
            fprintf('转移时间 = %.2f s (%.2f h)\n', T_trans, T_trans/3600);

            % ==========================================
            % 3. 仿真过程 (分三段积分)
            % ==========================================

            options = odeset('RelTol', 1e-8, 'AbsTol', 1e-8);

            % --- 第一段：停泊轨道 (仿真 2000秒) ---
            t_park = 2000;
            y0_park = [r1; 0; 0; 0; v1_circ; 0]; % 初始状态 [x, y, z, vx, vy, vz]
            [T1, Y1] = ode45(@(t,y) app.two_body_ode(t,y, app.mu_earth), [0, t_park], y0_park, options);

            % --- 第一次变轨 (在第一段末尾加 dv1) ---
            state_1_end = Y1(end, :)';
            % 速度方向主要在 Y 轴 (因为从 X 轴出发，运动到这里主要切向速度在 Y)
            % 更严谨的做法是把 dv 加在速度矢量方向上
            v_vec = state_1_end(4:6);
            v_dir = v_vec / norm(v_vec);
            state_1_end(4:6) = state_1_end(4:6) + dv1 * v_dir;

            % --- 第二段：转移轨道 (仿真 T_trans 时间) ---
            [T2, Y2] = ode45(@(t,y) app.two_body_ode(t,y, app.mu_earth), [t_park, t_park + T_trans], state_1_end, options);

            % --- 第二次变轨 (在第二段末尾加 dv2) ---
            state_2_end = Y2(end, :)';
            v_vec2 = state_2_end(4:6);
            v_dir2 = v_vec2 / norm(v_vec2);
            state_2_end(4:6) = state_2_end(4:6) + dv2 * v_dir2;

            % --- 第三段：GEO 轨道 (仿真 1个周期) ---
            T_geo = 2 * pi * sqrt(r2^3 / app.mu_earth);
            [T3, Y3] = ode45(@(t,y) app.two_body_ode(t,y, app.mu_earth), ...
                [T2(end), T2(end) + T_geo*0.8], state_2_end, options); % *0.8 是为了不画太长

            % ==========================================
            % 4. 绘图 (注意单位转换：为了好看，除以地球半径 Re)
            % ==========================================
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % 绘制地球
            [sx, sy, sz] = sphere(50);
            p_earth = surf(app.UIAxes, sx, sy, sz, 'FaceColor', 'blue', ...
                'EdgeColor', 'none', 'FaceAlpha', 0.3, ...
                'DisplayName', '地球'); % <--- 关键修改：指定名字

            % 绘制三段轨道 (坐标除以 Re 归一化显示)
            plot3(app.UIAxes, Y1(:,1)/app.Re, Y1(:,2)/app.Re, Y1(:,3)/app.Re, 'm-', 'LineWidth', 2, 'DisplayName', '停泊轨道 (200km)');
            plot3(app.UIAxes, Y2(:,1)/app.Re, Y2(:,2)/app.Re, Y2(:,3)/app.Re, 'g-', 'LineWidth', 1.5, 'DisplayName', '转移轨道');
            plot3(app.UIAxes, Y3(:,1)/app.Re, Y3(:,2)/app.Re, Y3(:,3)/app.Re, 'b-', 'LineWidth', 2, 'DisplayName', 'GEO 轨道');

            % 标记点火点
            p1 = Y1(end, 1:3) / app.Re;
            p2 = Y2(end, 1:3) / app.Re;
            plot3(app.UIAxes, p1(1), p1(2), p1(3), 'r*', 'MarkerSize', 10, 'DisplayName', '第一次点火');
            plot3(app.UIAxes, p2(1), p2(2), p2(3), 'r*', 'MarkerSize', 10, 'DisplayName', '第二次点火');

            % 调整视角
            axis(app.UIAxes, 'equal');
            legend(app.UIAxes, 'Location', 'best');
            title(app.UIAxes, sprintf('霍曼转移仿真\n\\DeltaV_1=%.3f km/s, \\DeltaV_2=%.3f km/s', dv1, dv2));

            hold(app.UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. 设置坐标轴属性 (解决你的 3D 视图问题)
            view(app.UIAxes, 3);          % 设置为 3D 视角
            grid(app.UIAxes, 'on');       % 打开网格
            axis(app.UIAxes, 'equal');    % 保证球体是圆的，不是扁的
            app.UIAxes.Box = 'on';        % 加上边框

            % 解释：
            % 距离单位 (DU) = 1 地球半径 (~6378 km)
            % 速度单位 (VU) = 第一宇宙速度 (~7.91 km/s)
            % 这样滑块的范围 [-4, 4] 刚好能覆盖从近地轨道到同步轨道的范围

            title(app.UIAxes, '轨道机动仿真 (单位: 地球半径)');
            xlabel(app.UIAxes, 'X (Earth Radii)');
            ylabel(app.UIAxes, 'Y (Earth Radii)');
            zlabel(app.UIAxes, 'Z (Earth Radii)');
            app.UIAxes.FontSize = 12; 

            % 2. 初始化滑块设置
            app.ttSlider.Limits = [0 40];
            app.ttSlider.Value = 20;
            app.v1Slider.Limits = [-4 4];
            app.v1Slider.Value = 0;
            app.v2Slider.Limits = [-4 4];
            app.v2Slider.Value = 0;
            app.v3Slider.Limits = [-4 4];
            app.v3Slider.Value = 0;
            app.t0Slider.Limits = [0 20];
            app.t0Slider.Value = 0;

            % 3. 初始画图
            updatePlot(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ttSlider
        function ttSliderValueChanged(app, event)
            app.ttSlider.Value = event.Value; % 强制更新属性值以便函数读取
            app.updatePlot(); % 调用绘图函数
        end

        % Value changed function: v1Slider
        function v1SliderValueChanged(app, event)
            app.v1Slider.Value = event.Value; % 强制更新属性值以便函数读取
            app.updatePlot(); % 调用绘图函数
        end

        % Value changed function: v2Slider
        function v2SliderValueChanged(app, event)
            app.v2Slider.Value = event.Value; % 强制更新属性值以便函数读取
            app.updatePlot(); % 调用绘图函数
        end

        % Value changed function: v3Slider
        function v3SliderValueChanged(app, event)
            app.v3Slider.Value = event.Value; % 强制更新属性值以便函数读取
            app.updatePlot(); % 调用绘图函数
        end

        % Value changing function: t0Slider
        function t0SliderValueChanging(app, event)
            app.t0Slider.Value = event.Value; % 强制更新属性值以便函数读取
            app.updatePlot(); % 调用绘图函数
        end

        % Value changed function: HohmannButton
        function HohmannButtonValueChanged(app, event)
            app.runHohmannTransfer();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '轨道机动仿真')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ttSlider = uislider(app.GridLayout);
            app.ttSlider.Limits = [0 40];
            app.ttSlider.ValueChangedFcn = createCallbackFcn(app, @ttSliderValueChanged, true);
            app.v1SliderLabel = uilabel(app.GridLayout);
            app.v1SliderLabel.Text = 'v1';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'tt';
            app.v1Slider = uislider(app.GridLayout);
            app.v1Slider.Limits = [-4 4];
            app.v1Slider.ValueChangedFcn = createCallbackFcn(app, @v1SliderValueChanged, true);
            app.v2SliderLabel = uilabel(app.GridLayout);
            app.v2SliderLabel.Text = 'v2';
            app.v2Slider = uislider(app.GridLayout);
            app.v2Slider.Limits = [-4 4];
            app.v2Slider.ValueChangedFcn = createCallbackFcn(app, @v2SliderValueChanged, true);
            app.v3SliderLabel = uilabel(app.GridLayout);
            app.v3SliderLabel.Text = 'v3';
            app.v3Slider = uislider(app.GridLayout);
            app.v3Slider.Limits = [-4 4];
            app.v3Slider.ValueChangedFcn = createCallbackFcn(app, @v3SliderValueChanged, true);
            app.t0SliderLabel = uilabel(app.GridLayout);
            app.t0SliderLabel.Text = 't0';
            app.t0Slider = uislider(app.GridLayout);
            app.t0Slider.Limits = [0 20];
            app.t0Slider.ValueChangingFcn = createCallbackFcn(app, @t0SliderValueChanging, true);
            app.HohmannButton = uibutton(app.GridLayout, 'state');
            app.HohmannButton.ValueChangedFcn = createCallbackFcn(app, @HohmannButtonValueChanged, true);
            app.HohmannButton.Text = 'HohmannButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end